classdef lf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        SliderLabel   matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        Slider2Label  matlab.ui.control.Label
        Slider2       matlab.ui.control.Slider
        Slider3Label  matlab.ui.control.Label
        Slider3       matlab.ui.control.Slider
        Slider4Label  matlab.ui.control.Label
        Slider4       matlab.ui.control.Slider
        RightPanel    matlab.ui.container.Panel
        Label         matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        img_width = 320
        img_height = 240
        imgs = zeros(16, 16, 240, 320, 3) 
    end
    
    methods (Access = private)
        
        function img = img_show(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 根据当前属性， 输出240*320*3图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            img = reshape(app.imgs(1, 1, :, :, :), app.img_height, app.img_width, 3);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化函数
            app.Lamp.Color = 'red';
            img_path_list = dir('*.bmp');
            for i = 1:16
                for j=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
            app.Lamp.Color = 'green';
            disp("数据加载完成");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Lamp.Color = 'red';
            value = app.Slider.Value;
            disp(value);
            
            
            img = app.img_show();
            imshow(img, 'Parent',app.UIAxes);
            app.Lamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tooltip = {''};
            app.Slider2Label = uilabel(app.LeftPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.LeftPanel);
            app.Slider3Label = uilabel(app.LeftPanel);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.LeftPanel);
            app.Slider4Label = uilabel(app.LeftPanel);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '处理中';
            app.Lamp = uilamp(app.RightPanel);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end